classdef PlotFunction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        XminEditFieldLabel      matlab.ui.control.Label
        XminEditField           matlab.ui.control.NumericEditField
        XmaxEditFieldLabel      matlab.ui.control.Label
        XmaxEditField           matlab.ui.control.NumericEditField
        FunctionEditFieldLabel  matlab.ui.control.Label
        FunctionEditField       matlab.ui.control.EditField
        PlotButton              matlab.ui.control.Button
        LineColorDropDownLabel  matlab.ui.control.Label
        LineColorDropDown       matlab.ui.control.DropDown
        LineStyleLabel          matlab.ui.control.Label
        LineStyleKnob           matlab.ui.control.DiscreteKnob
        LineWidthLabel          matlab.ui.control.Label
        LineWidthSlider         matlab.ui.control.Slider
    end


    properties (Access = private)
        Func(1,1) function_handle = @sin; % Handle to function
        Plot = '';   % Handle to plot
    end

    methods (Access = private)
    
        function updatePlot(app)
            xmin = app.XminEditField.Value;
            xmax = app.XmaxEditField.Value;
            if xmin >= xmax
                uialert(app.UIFigure, 'Xmin must be less than Xmax', 'Error');
            else
                app.Plot = fplot(app.UIAxes, app.Func, [xmin xmax],...
                    'Color', app.LineColorDropDown.Value,...
                    'LineStyle', app.LineStyleKnob.Value,...
                    'LineWidth', app.LineWidthSlider.Value);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Plot = fplot(app.UIAxes, app.Func,...
                [app.XminEditField.Value app.XmaxEditField.Value], 'Color', 'black');
            app.LineWidthSlider.MajorTicks = 1:10;
            app.LineWidthSlider.MinorTicks = 0.5:10;
        end

        % Value changed function: FunctionEditField
        function FunctionEditFieldValueChanged(app, event)
            try
                app.Func = str2func(app.FunctionEditField.Value);
                updatePlot(app);
            catch me
                uialert(app.UIFigure, me.message, 'Error');
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            updatePlot(app)
        end

        % Value changed function: LineColorDropDown
        function LineColorDropDownValueChanged(app, event)
            app.Plot.Color = app.LineColorDropDown.Value;
        end

        % Value changed function: LineStyleKnob
        function LineStyleKnobValueChanged(app, event)
            app.Plot.LineStyle = app.LineStyleKnob.Value;
        end

        % Value changing function: LineWidthSlider
        function LineWidthSliderValueChanging(app, event)
            app.Plot.LineWidth = event.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField.Value = 6.2;
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.ValueChangedFcn = createCallbackFcn(app, @FunctionEditFieldValueChanged, true);
            app.FunctionEditField.Value = '@sin';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LineColorDropDownLabel = uilabel(app.UIFigure);
            app.LineColorDropDownLabel.Text = 'LineColor';
            app.LineColorDropDown = uidropdown(app.UIFigure);
            app.LineColorDropDown.Items = {'black', 'blue', 'red', 'green', 'yellow', 'magenta', 'cyan'};
            app.LineColorDropDown.ValueChangedFcn = createCallbackFcn(app, @LineColorDropDownValueChanged, true);
            app.LineColorDropDown.Value = 'black';
            app.LineStyleLabel = uilabel(app.UIFigure);
            app.LineStyleLabel.Text = 'LineStyle';
            app.LineStyleKnob = uiknob(app.UIFigure, 'discrete');
            app.LineStyleKnob.Items = {'Solid', 'Dash', 'Dot', 'Dash-Dot'};
            app.LineStyleKnob.ItemsData = {'-', '--', ':', '-.'};
            app.LineStyleKnob.ValueChangedFcn = createCallbackFcn(app, @LineStyleKnobValueChanged, true);
            app.LineStyleKnob.Value = '-';
            app.LineWidthLabel = uilabel(app.UIFigure);
            app.LineWidthLabel.Text = 'LineWidth';
            app.LineWidthSlider = uislider(app.UIFigure);
            app.LineWidthSlider.Limits = [0.5 10];
            app.LineWidthSlider.ValueChangingFcn = createCallbackFcn(app, @LineWidthSliderValueChanging, true);
            app.LineWidthSlider.Value = 0.5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = PlotFunction

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end