classdef SimpleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        surfButton               matlab.ui.control.Button
        meshButton               matlab.ui.control.Button
        contourfButton           matlab.ui.control.Button
        SelectDataDropDownLabel  matlab.ui.control.Label
        SelectDataDropDown       matlab.ui.control.DropDown
        GridCheckBox             matlab.ui.control.CheckBox
        ColormapListBoxLabel     matlab.ui.control.Label
        ColormapListBox          matlab.ui.control.ListBox
    end


    properties (Access = private)
        peaksData;
        membraneData;
        sincData;
        currentData;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.peaksData = peaks(40);
            app.membraneData = membrane;
            [x,y] = meshgrid(-8:.25:8);
            r = sqrt(x.^2+y.^2) + eps;
            app.sincData = sin(r)./r;
            
            app.currentData = app.peaksData;
            surf(app.UIAxes, app.currentData);
            
            zlabel(app.UIAxes, 'Z')
            
            % Fill the List Box with the standard colormap names
            maps = {'default', 'parula', 'jet' , 'hsv', 'hot', 'cool', 'spring',...
                'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink',...
                'lines', 'colorcube', 'prism', 'flag', 'white'};
            app.ColormapListBox.Items = maps;
        end

        % Button pushed function: surfButton
        function surfButtonPushed(app, event)
            surf(app.UIAxes, app.currentData);
        end

        % Button pushed function: meshButton
        function meshButtonPushed(app, event)
            mesh(app.UIAxes, app.currentData);
        end

        % Button pushed function: contourfButton
        function contourfButtonPushed(app, event)
            contourf(app.UIAxes, app.currentData);
        end

        % Value changed function: SelectDataDropDown
        function SelectDataDropDownValueChanged(app, event)
            value = app.SelectDataDropDown.Value;
            switch value
                case 'peaks'
                    app.currentData = app.peaksData;
                case 'membrane'
                    app.currentData = app.membraneData;
                case 'sinc'
                    app.currentData = app.sincData;
            end
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if value
                grid(app.UIAxes, 'on');
            else
                grid(app.UIAxes, 'off');
            end
        end

        % Value changed function: ColormapListBox
        function ColormapListBoxValueChanged(app, event)
            value = app.ColormapListBox.Value;
            colormap(app.UIAxes, value)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.surfButton = uibutton(app.UIFigure, 'push');
            app.surfButton.ButtonPushedFcn = createCallbackFcn(app, @surfButtonPushed, true);
            app.surfButton.Text = 'surf';
            app.meshButton = uibutton(app.UIFigure, 'push');
            app.meshButton.ButtonPushedFcn = createCallbackFcn(app, @meshButtonPushed, true);
            app.meshButton.Text = 'mesh';
            app.contourfButton = uibutton(app.UIFigure, 'push');
            app.contourfButton.ButtonPushedFcn = createCallbackFcn(app, @contourfButtonPushed, true);
            app.contourfButton.Text = 'contourf';
            app.SelectDataDropDownLabel = uilabel(app.UIFigure);
            app.SelectDataDropDownLabel.Text = 'Select Data';
            app.SelectDataDropDown = uidropdown(app.UIFigure);
            app.SelectDataDropDown.Items = {'peaks', 'membrane', 'sinc'};
            app.SelectDataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDataDropDownValueChanged, true);
            app.SelectDataDropDown.Value = 'peaks';
            app.GridCheckBox = uicheckbox(app.UIFigure);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.ColormapListBoxLabel = uilabel(app.UIFigure);
            app.ColormapListBoxLabel.Text = 'Colormap';
            app.ColormapListBox = uilistbox(app.UIFigure);
            app.ColormapListBox.Items = {};
            app.ColormapListBox.ValueChangedFcn = createCallbackFcn(app, @ColormapListBoxValueChanged, true);
            app.ColormapListBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimpleApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end